classdef Labeling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        Label           matlab.ui.control.Label
        EditField_2     matlab.ui.control.EditField
        Button          matlab.ui.control.Button
        Label_2         matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        EditField_3     matlab.ui.control.EditField
        OLabel          matlab.ui.control.Label
        Panel           matlab.ui.container.Panel
        Button_2        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        Label_4         matlab.ui.control.Label
        EditField_4     matlab.ui.control.NumericEditField
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img_idx;
        str;
    end
    
    methods (Access = private)
        
        function results = save(app)
            data = table(app.str(:,1), app.str(:,2));
            writetable( data, 'labels.csv', 'WriteVariableNames', false );
            app.EditField_2.Value = "Data saved";
        end
        
        function results = showgif(app)
            filename = ['.\gifs\', num2str(app.img_idx) '.gif'];
            app.EditField_3.Value = filename;
            if isfile( filename )
                [img,map] = imread( filename, 'frames', 'all' );
                bigimage = imtile( img, map, 'GridSize', [9 3]);
                imshow( bigimage, map, 'Parent', app.UIAxes );
                if size(app.str,1) > app.img_idx
                    app.EditField.Value = app.str(app.img_idx,2);
                else
                    app.EditField.Value = "";
                end
            else
                app.EditField_2.Value = "已经是最后一个，请保存文件";
                app.img_idx = app.img_idx - 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filename = 'labels.csv';
            if isfile( filename )
                data = readtable('labels.csv', 'ReadVariableNames', false);
                app.str = [ string(num2str(data{:,1})) , string(data{:,2}) ];
                app.img_idx = size(app.str,1);
            else
                app.str = strings(1,2);
                app.img_idx = 0;
            end
            showgif(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if strlength(value) == 4
                app.str(app.img_idx, 1) = [num2str(app.img_idx)];
                app.str(app.img_idx, 2) = replace(upper(value),'O','0');
                app.EditField.Value = "";
                app.EditField_2.Value = "";
                if mod(app.img_idx,50) == 0
                    save(app);
                end
                app.img_idx = app.img_idx + 1;
                showgif(app);
            else
                app.EditField_2.Value = "请重新输入";
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            save(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.img_idx > 1
                app.img_idx = app.img_idx - 1;
                showgif(app);
                app.EditField_2.Value = "";
            else
                app.EditField_2.Value = "已经是第一个数据";
            end      
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            filename = ['.\gifs\', num2str(app.img_idx+1) '.gif'];
            if isfile( filename )
                app.img_idx = app.img_idx + 1;
                showgif(app);
                app.EditField_2.Value = "";
            else
                app.EditField_2.Value = "已经是最后一个数据";
            end
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            filename = ['.\gifs\', num2str(value) '.gif'];
            if isfile(filename)
                if size(app.str,1) > value
                    app.img_idx = value;
                    showgif(app);
                    app.EditField_2.Value = "";
                    app.EditField_4.Value = 0;
                else
                    app.EditField_2.Value = "暂无标记，拒绝跳转";
                    app.EditField_4.Value = 0;
                end
            else
                app.EditField_2.Value = "无此文件，拒绝跳转";
                app.EditField_4.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '验证码';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '提示信息';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '保存';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '敲击回车以保存，所有输入会被自动转换为大写字母';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '文件名';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.OLabel = uilabel(app.UIFigure);
            app.OLabel.Text = '所有数字0会被转换成字母O';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '导航';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '上一个';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '下一个';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '导航至';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Labeling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end